classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GOButton                        matlab.ui.control.Button
        SourceimagesaddressEditFieldLabel  matlab.ui.control.Label
        SourceimagesaddressEditField    matlab.ui.control.EditField
        PleaseinputtheaddressofrequiredimagesLabel  matlab.ui.control.Label
        GoalimageaddressEditFieldLabel  matlab.ui.control.Label
        GoalimageaddressEditField       matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        TilesizexEditFieldLabel         matlab.ui.control.Label
        TilesizexEditField              matlab.ui.control.NumericEditField
        TilesizeyEditFieldLabel         matlab.ui.control.Label
        TilesizeyEditField              matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            if app.SourceimagesaddressEditField.Value == "" || app.GoalimageaddressEditField.Value ==""
                app.Label.Text = "Please fill both fields";
            else
                %Validate input, get images
                app.Label.Text = "Fetching images";
                try
                    goalpath = app.GoalimageaddressEditField.Value;
                    goal = imread(goalpath);
                    sourcepath = app.SourceimagesaddressEditField.Value;
                    sourcepath = strcat(sourcepath, '\\');
                    [sourceImg, len] = importimg(sourcepath);
                catch
                    app.Label.Text = "An error occured while importing images. Please check the directories and try again.";
                    return;
                end
                
                app.Label.Text = "Loading the classifier";
                classifier = load('classifier.mat', 'Mdl');
                Mdl = classifier.Mdl;
                
                
                %Categorise goal image
                app.Label.Text = "Classifying goal image";
                longest = longestLine(rgb2gray(goal))*1000;
                gHist = colourHist(goal,2);
                input = [longest, gHist'];
                input = reshape(input(1:9),[],9);
                goalClass = predict(Mdl, input);
                
                %Categorise source images
                app.Label.Text = "Classifying source images. Goal class: " + goalClass{1,1};
                counter = 0;
                sourceImgClass = cell(len, 1);
                for i = 1 : len
                    longest = longestLine(rgb2gray(sourceImg{i}))*1000;
                    gHist = colourHist(sourceImg{i},2);
                    inputSources = [longest, gHist'];
                    inputSources = reshape(inputSources(1:9),[],9);
                    sourceClasses = predict(Mdl, inputSources);
                    if goalClass{1,1} == sourceClasses{1, 1}
                        counter = counter + 1;
                        sourceImgClass{counter} = sourceImg{i};
                        app.Label.Text = {'found one', int2str(i)};
                    end
                    app.Label.Text = {'processing... ', int2str(i), ' of ', int2str(len)};
                end
                %Remove empty cells
                sourceImgClass = sourceImgClass(1:counter);
                len = size(sourceImgClass);
                save sourceIC sourceImgClass;
                %{
                %Get parts of the goal image, crop source images to size
                %app.Label.Text = "Cropping source images";
                
                
                a = app.TilesizexEditField.Value;
                b = app.TilesizeyEditField.Value;
                
                app.Label.Text = {'number of sources: ', int2str(len)};
                
                sources = sourcesToTiles(sourceImgClass, b, a);
                [goals, width, height] = cropimg_new(goal, a, b);
                %Take matching set, apply similarity measure to sections
                app.Label.Text = "Matching images to goal sections";
                img = matchImagesToSections(goals, sources, 1, 0.5, 4, 2);
                
                %Display the image
                app.Label.Text = "Drawing image";
                img = drawimg_new(img,width, height);
                imshow(img, 'Parent', app.UIAxes);
                app.Label.Text = "Done!";
                %}
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.SourceimagesaddressEditFieldLabel = uilabel(app.UIFigure);
            app.SourceimagesaddressEditFieldLabel.Text = 'Source images'' address:';
            app.SourceimagesaddressEditField = uieditfield(app.UIFigure, 'text');
            app.SourceimagesaddressEditField.Value = 'C:\Users\jimi\Documents\GitHub\AC41002---Composite-Image-Assignment\Images\Images\j';
            app.PleaseinputtheaddressofrequiredimagesLabel = uilabel(app.UIFigure);
            app.PleaseinputtheaddressofrequiredimagesLabel.Text = 'Please input the address of required images';
            app.GoalimageaddressEditFieldLabel = uilabel(app.UIFigure);
            app.GoalimageaddressEditFieldLabel.Text = 'Goal image address:';
            app.GoalimageaddressEditField = uieditfield(app.UIFigure, 'text');
            app.GoalimageaddressEditField.Value = 'C:\Users\jimi\Documents\GitHub\AC41002---Composite-Image-Assignment\Images\target.jpg';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TilesizexEditFieldLabel = uilabel(app.UIFigure);
            app.TilesizexEditFieldLabel.Text = 'Tile size (x)';
            app.TilesizexEditField = uieditfield(app.UIFigure, 'numeric');
            app.TilesizexEditField.Limits = [4 512];
            app.TilesizexEditField.Value = 64;
            app.TilesizeyEditFieldLabel = uilabel(app.UIFigure);
            app.TilesizeyEditFieldLabel.Text = 'Tile size (y)';
            app.TilesizeyEditField = uieditfield(app.UIFigure, 'numeric');
            app.TilesizeyEditField.Limits = [4 512];
            app.TilesizeyEditField.Value = 64;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end